classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        SpectralPlot                matlab.ui.control.UIAxes
        BoxcarWidthSliderLabel      matlab.ui.control.Label
        BoxcarWidthSlider           matlab.ui.control.Slider
        IntegrationTimeSliderLabel  matlab.ui.control.Label
        IntegrationTimeSlider       matlab.ui.control.Slider
        DarkBackgroundButton        matlab.ui.control.Button
        TakeMeasurementButton       matlab.ui.control.Button
    end


    properties (Access = private)
        CS                      CanfodSpectrometer  % CanfodSpectrometer class
        xdata                                       % Wavelength
        ydata                                       % Spectra
    end


    methods (Access = private)

        % Code that executes after component creation
        function importCanford(app)
            app.CS = CanfodSpectrometer();
            
            app.BoxcarWidthSlider.Value = app.CS.getBoxcarWidth;
            app.IntegrationTimeSlider.Value = app.CS.getIntegrationTime;
            
        end

        % Button pushed function: TakeMeasurementButton
        function TakeMeasurementButtonPushed(app, event)
            app.CS.pushAllParameters;
            app.CS.getMeasurement;
            plot(app.SpectralPlot, app.CS.wavelengths, app.CS.spectrum);
        end

        % Value changed function: BoxcarWidthSlider
        function BoxcarWidthSliderValueChanged(app, event)
            value = app.BoxcarWidthSlider.Value;
            app.CS.setBoxcarWidth(value);
        end

        % Value changed function: IntegrationTimeSlider
        function IntegrationTimeSliderValueChanged(app, event)
            value = app.IntegrationTimeSlider.Value;
            app.CS.setIntegrationTime(value);
        end

        % Button pushed function: DarkBackgroundButton
        function DarkBackgroundButtonPushed(app, event)

        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SpectralPlot = uiaxes(app.UIFigure);
            xlabel(app.SpectralPlot, 'Wavelength (nm)')
            ylabel(app.SpectralPlot, 'Intensity (au)')
            app.BoxcarWidthSliderLabel = uilabel(app.UIFigure);
            app.BoxcarWidthSliderLabel.Text = 'Boxcar Width';
            app.BoxcarWidthSlider = uislider(app.UIFigure);
            app.BoxcarWidthSlider.ValueChangedFcn = createCallbackFcn(app, @BoxcarWidthSliderValueChanged, true);
            app.IntegrationTimeSliderLabel = uilabel(app.UIFigure);
            app.IntegrationTimeSliderLabel.Text = 'Integration Time';
            app.IntegrationTimeSlider = uislider(app.UIFigure);
            app.IntegrationTimeSlider.ValueChangedFcn = createCallbackFcn(app, @IntegrationTimeSliderValueChanged, true);
            app.DarkBackgroundButton = uibutton(app.UIFigure, 'push');
            app.DarkBackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @DarkBackgroundButtonPushed, true);
            app.DarkBackgroundButton.Text = 'Dark Background';
            app.TakeMeasurementButton = uibutton(app.UIFigure, 'push');
            app.TakeMeasurementButton.ButtonPushedFcn = createCallbackFcn(app, @TakeMeasurementButtonPushed, true);
            app.TakeMeasurementButton.Text = 'Take Measurement';
        end
    end

    methods (Access = public)

        % Construct app
        function app = gui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @importCanford)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end